classdef SpaceTimeGLOBE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ObserverBSlider       matlab.ui.control.Slider
        ObserverBSliderLabel  matlab.ui.control.Label
        ObserverASlider       matlab.ui.control.Slider
        ObserverASliderLabel  matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ObserverASlider, ObserverBSlider
        function ObserverASliderValueChanged(app, event)
            v0 = app.ObserverASlider.Value;
            vm = app.ObserverBSlider.Value;

            % Create the grids
            x = app.UIAxes.XTick;
            t = app.UIAxes.YTick;

            % Relative velocity and Lorentz transformation
            vr = (vm-v0)/(1-v0*vm);
            c = 1;
            gamma = 1/sqrt(1-vr^2);
            % Change of coordinate
            tnew = gamma*(t - vr*x);
            %xnew = gamma*(x - vr*t);
            t0 = t/gamma;

            % Plot the lines
            plot(app.UIAxes,0*t,t,'g',-v0*tnew,tnew,'g',  -c*t,t,'y',c*t,t,'y', LineWidth=2);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes,v0*t,t,'r',0*t0,t0,'r', LineStyle='--',LineWidth=3);
            plot(app.UIAxes,vm*t,t,'b',vr*tnew,tnew,'b', LineStyle='--',LineWidth=3);

            % Label the lines
            text(app.UIAxes,c*50,-50,"Light",FontSize=15,FontWeight="bold");
            text(app.UIAxes,-c*50,-50,"Light",FontSize=15,FontWeight="bold");
            text(app.UIAxes,v0*45,45,"A",FontSize=15,FontWeight="bold");
            text(app.UIAxes,0,55,"A'",FontSize=15,FontWeight="bold");
            text(app.UIAxes,vm*40,40,"B",FontSize=15,FontWeight="bold");
            text(app.UIAxes,vr*60,60,"B'",FontSize=15,FontWeight="bold");
            text(app.UIAxes,-v0*48,48,"Rest'",FontSize=15,FontWeight="bold");
            text(app.UIAxes,0,68,"Rest",FontSize=15,FontWeight="bold");
            hold(app.UIAxes, 'off');
            drawnow;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Space-Time Globe')
            xlabel(app.UIAxes, 'X(Space)')
            ylabel(app.UIAxes, 'Y(Time)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [-80 -70 -60 -50 -40 -30 -20 -10 0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 10 20 30 40 50 60 70 80];
            app.UIAxes.XTickLabel = {'-80'; '-70'; '-60'; '-50'; '-40'; '-30'; '-20'; '-10'; '0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'; '10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'};
            app.UIAxes.YTick = [-80 -70 -60 -50 -40 -30 -20 -10 0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 10 20 30 40 50 60 70 80];
            app.UIAxes.YTickLabel = {'-80'; '-70'; '-60'; '-50'; '-40'; '-30'; '-20'; '-10'; '0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'; '10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'};
            app.ObserverASliderLabel = uilabel(app.UIFigure);
            app.ObserverASliderLabel.Text = 'Observer A';
            app.ObserverASlider = uislider(app.UIFigure);
            app.ObserverASlider.Limits = [0 1];
            app.ObserverASlider.ValueChangedFcn = createCallbackFcn(app, @ObserverASliderValueChanged, true);
            app.ObserverASlider.Tag = 'Slider';
            app.ObserverBSliderLabel = uilabel(app.UIFigure);
            app.ObserverBSliderLabel.Text = 'Observer B';
            app.ObserverBSlider = uislider(app.UIFigure);
            app.ObserverBSlider.Limits = [0 1];
            app.ObserverBSlider.ValueChangedFcn = createCallbackFcn(app, @ObserverASliderValueChanged, true);
            app.ObserverBSlider.Tag = 'Slider';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpaceTimeGLOBE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end